classdef fmelementos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClearallButton                  matlab.ui.control.Button
        MasamolEditField                matlab.ui.control.NumericEditField
        MasamolEditFieldLabel           matlab.ui.control.Label
        CalcularmasaButton              matlab.ui.control.Button
        PorcentajetotalEditField        matlab.ui.control.NumericEditField
        PorcentajetotalEditFieldLabel   matlab.ui.control.Label
        SolutoEditField                 matlab.ui.control.NumericEditField
        SolutoEditFieldLabel            matlab.ui.control.Label
        DisolventeEditField             matlab.ui.control.NumericEditField
        DisolventeEditFieldLabel        matlab.ui.control.Label
        ElementosdeldisolventeButtonGroup  matlab.ui.container.ButtonGroup
        Nmerodemolculas6Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas6Spinner_3Label  matlab.ui.control.Label
        Nmerodemolculas5Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas5Spinner_3Label  matlab.ui.control.Label
        Nmerodemolculas4Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas4Spinner_3Label  matlab.ui.control.Label
        Nmerodemolculas3Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas3Spinner_3Label  matlab.ui.control.Label
        Nmerodemolculas2Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas2Spinner_3Label  matlab.ui.control.Label
        Nmerodemolculas1Spinner_3       matlab.ui.control.Spinner
        Nmerodemolculas1Spinner_3Label  matlab.ui.control.Label
        Msaatmica6EditField_3           matlab.ui.control.NumericEditField
        Msaatmica6EditField_3Label      matlab.ui.control.Label
        Msaatmica4EditField_3           matlab.ui.control.NumericEditField
        Msaatmica4EditField_3Label      matlab.ui.control.Label
        Msaatmica5EditField_3           matlab.ui.control.NumericEditField
        Msaatmica5EditField_3Label      matlab.ui.control.Label
        Msaatmica1EditField_5           matlab.ui.control.NumericEditField
        Msaatmica1EditField_5Label      matlab.ui.control.Label
        Msaatmica2EditField_3           matlab.ui.control.NumericEditField
        Msaatmica2EditField_3Label      matlab.ui.control.Label
        Msaatmica1EditField_3           matlab.ui.control.NumericEditField
        Msaatmica1EditField_3Label      matlab.ui.control.Label
        PorcentajeEditField             matlab.ui.control.NumericEditField
        PorcentajeEditFieldLabel        matlab.ui.control.Label
        FraccinmolmasaEditField         matlab.ui.control.NumericEditField
        FraccinmolmasaEditFieldLabel    matlab.ui.control.Label
        GramosmolesdesolutoEditField    matlab.ui.control.NumericEditField
        GramosmolesdesolutoEditFieldLabel  matlab.ui.control.Label
        GramosmolesdedisolventeEditField  matlab.ui.control.NumericEditField
        GramosmolesdedisolventeEditFieldLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        fmLabel                         matlab.ui.control.Label
        FraccinmolmasaLabel             matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        MenButton                       matlab.ui.control.Button
        ElementosdelsolutoButtonGroup   matlab.ui.container.ButtonGroup
        Nmerodemolculas6Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas6Spinner_2Label  matlab.ui.control.Label
        Nmerodemolculas5Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas5Spinner_2Label  matlab.ui.control.Label
        Nmerodemolculas4Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas4Spinner_2Label  matlab.ui.control.Label
        Nmerodemolculas3Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas3Spinner_2Label  matlab.ui.control.Label
        Nmerodemolculas2Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas2Spinner_2Label  matlab.ui.control.Label
        Nmerodemolculas1Spinner_2       matlab.ui.control.Spinner
        Nmerodemolculas1Spinner_2Label  matlab.ui.control.Label
        Msaatmica6EditField_2           matlab.ui.control.NumericEditField
        Msaatmica6EditField_2Label      matlab.ui.control.Label
        Msaatmica4EditField_2           matlab.ui.control.NumericEditField
        Msaatmica4EditField_2Label      matlab.ui.control.Label
        Msaatmica5EditField_2           matlab.ui.control.NumericEditField
        Msaatmica5EditField_2Label      matlab.ui.control.Label
        Msaatmica3EditField_2           matlab.ui.control.NumericEditField
        Msaatmica3EditField_2Label      matlab.ui.control.Label
        Msaatmica2EditField_2           matlab.ui.control.NumericEditField
        Msaatmica2EditField_2Label      matlab.ui.control.Label
        Msaatmica1EditField_2           matlab.ui.control.NumericEditField
        Msaatmica1EditField_2Label      matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MenButton
        function MenButtonPushed(app, event)
            menufmol
            delete(app)
        end

        % Callback function
        function DropDownValueChanged(app, event)
       
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            nmd6=app.Nmerodemolculas6Spinner_3.Value;
            nmd5=app.Nmerodemolculas5Spinner_3.Value;
            nmd4=app.Nmerodemolculas4Spinner_3.Value;
            nmd3=app.Nmerodemolculas3Spinner_3.Value;
            nmd2=app.Nmerodemolculas2Spinner_3.Value;
            nmd1=app.Nmerodemolculas1Spinner_3.Value;
            mad6=app.Msaatmica6EditField_3.Value;
            mad5=app.Msaatmica5EditField_3.Value;
            mad4=app.Msaatmica4EditField_3.Value;
            mad3=app.Msaatmica1EditField_5.Value;
            mad2=app.Msaatmica2EditField_3.Value;
            mad1=app.Msaatmica1EditField_3.Value;
            
            nms6=app.Nmerodemolculas6Spinner_2.Value;
            nms5=app.Nmerodemolculas5Spinner_2.Value;
            nms4=app.Nmerodemolculas4Spinner_2.Value;
            nms3=app.Nmerodemolculas3Spinner_2.Value;
            nms2=app.Nmerodemolculas2Spinner_2.Value;
            nms1=app.Nmerodemolculas1Spinner_2.Value;
            mas6=app.Msaatmica6EditField_2.Value;
            mas5=app.Msaatmica5EditField_2.Value;
            mas4=app.Msaatmica4EditField_2.Value;
            mas3=app.Msaatmica3EditField_2.Value;
            mas2=app.Msaatmica2EditField_2.Value;
            mas1=app.Msaatmica1EditField_2.Value;

            gs=app.GramosmolesdesolutoEditField.Value;
            gd=app.GramosmolesdedisolventeEditField.Value;

            Mtd=(nmd1*mad1)+(nmd2*mad2)+(nmd3*mad3)+(nmd4*mad4)+(nmd5*mad5)+(nmd6*mad6);
            nd=gd/Mtd;
            app.DisolventeEditField.Value=nd;

            Mts=(nms1*mas1)+(nms2*mas2)+(nms3*mas3)+(nms4*mas4)+(nms5*mas5)+(nms6*mas6);
            ns=gs/Mts;
            app.SolutoEditField.Value=ns;

            app.PorcentajetotalEditField.Value=ns+nd;

            fm=((ns)/(ns+nd));

            app.FraccinmolmasaEditField.Value=fm;

            fmp=fm*100;
            app.PorcentajeEditField.Value=fmp;
        end

        % Button pushed function: CalcularmasaButton
        function CalcularmasaButtonPushed(app, event)
            fm=app.FraccinmolmasaEditField.Value;
            t=app.PorcentajetotalEditField.Value;
            m=fm/t;
            app.MasamolEditField.Value=m;
        end

        % Button pushed function: ClearallButton
        function ClearallButtonPushed(app, event)
            app.Nmerodemolculas6Spinner_3.Value=0;
            app.Nmerodemolculas5Spinner_3.Value=0;
            app.Nmerodemolculas4Spinner_3.Value=0;
            app.Nmerodemolculas3Spinner_3.Value=0;
            app.Nmerodemolculas2Spinner_3.Value=0;
            app.Nmerodemolculas1Spinner_3.Value=0;
            app.Msaatmica6EditField_3.Value=0;
            app.Msaatmica5EditField_3.Value=0;
            app.Msaatmica4EditField_3.Value=0;
            app.Msaatmica1EditField_5.Value=0;
            app.Msaatmica2EditField_3.Value=0;
            app.Msaatmica1EditField_3.Value=0;
            
            app.Nmerodemolculas6Spinner_2.Value=0;
            app.Nmerodemolculas5Spinner_2.Value=0;
            app.Nmerodemolculas4Spinner_2.Value=0;
            app.Nmerodemolculas3Spinner_2.Value=0;
            app.Nmerodemolculas2Spinner_2.Value=0;
            app.Nmerodemolculas1Spinner_2.Value=0;
            app.Msaatmica6EditField_2.Value=0;
            app.Msaatmica5EditField_2.Value=0;
            app.Msaatmica4EditField_2.Value=0;
            app.Msaatmica3EditField_2.Value=0;
            app.Msaatmica2EditField_2.Value=0;
            app.Msaatmica1EditField_2.Value=0;

            app.GramosmolesdesolutoEditField.Value=0;
            app.GramosmolesdedisolventeEditField.Value=0;

            app.DisolventeEditField.Value=0;
            app.SolutoEditField.Value=0;
            app.PorcentajetotalEditField.Value=0;
            app.FraccinmolmasaEditField.Value=0;
            app.PorcentajeEditField.Value=0;
            app.MasamolEditField.Value=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ElementosdelsolutoButtonGroup = uibuttongroup(app.UIFigure);
            app.ElementosdelsolutoButtonGroup.Title = 'Elementos del soluto';
            app.Msaatmica1EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica1EditField_2Label.Text = 'Mása atómica 1';
            app.Msaatmica1EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Msaatmica2EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica2EditField_2Label.Text = 'Mása atómica 2';
            app.Msaatmica2EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Msaatmica3EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica3EditField_2Label.Text = 'Mása atómica 3';
            app.Msaatmica3EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Msaatmica5EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica5EditField_2Label.Text = 'Mása atómica 5';
            app.Msaatmica5EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Msaatmica4EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica4EditField_2Label.Text = 'Mása atómica 4';
            app.Msaatmica4EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Msaatmica6EditField_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Msaatmica6EditField_2Label.Text = 'Mása atómica 6';
            app.Msaatmica6EditField_2 = uieditfield(app.ElementosdelsolutoButtonGroup, 'numeric');
            app.Nmerodemolculas1Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas1Spinner_2Label.Text = 'Número de moléculas 1';
            app.Nmerodemolculas1Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas2Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas2Spinner_2Label.Text = 'Número de moléculas 2';
            app.Nmerodemolculas2Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas3Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas3Spinner_2Label.Text = 'Número de moléculas 3';
            app.Nmerodemolculas3Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas4Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas4Spinner_2Label.Text = 'Número de moléculas 4';
            app.Nmerodemolculas4Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas5Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas5Spinner_2Label.Text = 'Número de moléculas 5';
            app.Nmerodemolculas5Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas6Spinner_2Label = uilabel(app.ElementosdelsolutoButtonGroup);
            app.Nmerodemolculas6Spinner_2Label.Text = 'Número de moléculas 6';
            app.Nmerodemolculas6Spinner_2 = uispinner(app.ElementosdelsolutoButtonGroup);
            app.MenButton = uibutton(app.UIFigure, 'push');
            app.MenButton.ButtonPushedFcn = createCallbackFcn(app, @MenButtonPushed, true);
            app.MenButton.Icon = '7530664.png';
            app.MenButton.Text = 'Menú';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.FraccinmolmasaLabel = uilabel(app.UIFigure);
            app.FraccinmolmasaLabel.Text = 'Fracción mol / masa';
            app.fmLabel = uilabel(app.UIFigure);
            app.fmLabel.Text = 'fm';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.GramosmolesdedisolventeEditFieldLabel = uilabel(app.UIFigure);
            app.GramosmolesdedisolventeEditFieldLabel.Text = 'Gramos / moles de disolvente';
            app.GramosmolesdedisolventeEditField = uieditfield(app.UIFigure, 'numeric');
            app.GramosmolesdesolutoEditFieldLabel = uilabel(app.UIFigure);
            app.GramosmolesdesolutoEditFieldLabel.Text = 'Gramos / moles de soluto';
            app.GramosmolesdesolutoEditField = uieditfield(app.UIFigure, 'numeric');
            app.FraccinmolmasaEditFieldLabel = uilabel(app.UIFigure);
            app.FraccinmolmasaEditFieldLabel.Text = 'Fracción mol / masa';
            app.FraccinmolmasaEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajeEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajeEditFieldLabel.Text = 'Porcentaje';
            app.PorcentajeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElementosdeldisolventeButtonGroup = uibuttongroup(app.UIFigure);
            app.ElementosdeldisolventeButtonGroup.Title = 'Elementos del disolvente';
            app.Msaatmica1EditField_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica1EditField_3Label.Text = 'Mása atómica 1';
            app.Msaatmica1EditField_3 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Msaatmica2EditField_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica2EditField_3Label.Text = 'Mása atómica 2';
            app.Msaatmica2EditField_3 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Msaatmica1EditField_5Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica1EditField_5Label.Text = 'Mása atómica 1';
            app.Msaatmica1EditField_5 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Msaatmica5EditField_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica5EditField_3Label.Text = 'Mása atómica 5';
            app.Msaatmica5EditField_3 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Msaatmica4EditField_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica4EditField_3Label.Text = 'Mása atómica 4';
            app.Msaatmica4EditField_3 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Msaatmica6EditField_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Msaatmica6EditField_3Label.Text = 'Mása atómica 6';
            app.Msaatmica6EditField_3 = uieditfield(app.ElementosdeldisolventeButtonGroup, 'numeric');
            app.Nmerodemolculas1Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas1Spinner_3Label.Text = 'Número de moléculas 1';
            app.Nmerodemolculas1Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas2Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas2Spinner_3Label.Text = 'Número de moléculas 2';
            app.Nmerodemolculas2Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas3Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas3Spinner_3Label.Text = 'Número de moléculas 3';
            app.Nmerodemolculas3Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas4Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas4Spinner_3Label.Text = 'Número de moléculas 4';
            app.Nmerodemolculas4Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas5Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas5Spinner_3Label.Text = 'Número de moléculas 5';
            app.Nmerodemolculas5Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas6Spinner_3Label = uilabel(app.ElementosdeldisolventeButtonGroup);
            app.Nmerodemolculas6Spinner_3Label.Text = 'Número de moléculas 6';
            app.Nmerodemolculas6Spinner_3 = uispinner(app.ElementosdeldisolventeButtonGroup);
            app.DisolventeEditFieldLabel = uilabel(app.UIFigure);
            app.DisolventeEditFieldLabel.Text = 'Disolvente';
            app.DisolventeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SolutoEditFieldLabel = uilabel(app.UIFigure);
            app.SolutoEditFieldLabel.Text = 'Soluto';
            app.SolutoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PorcentajetotalEditFieldLabel = uilabel(app.UIFigure);
            app.PorcentajetotalEditFieldLabel.Text = 'Porcentaje total';
            app.PorcentajetotalEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularmasaButton = uibutton(app.UIFigure, 'push');
            app.CalcularmasaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularmasaButtonPushed, true);
            app.CalcularmasaButton.Text = {'Calcular masa'; ''};
            app.MasamolEditFieldLabel = uilabel(app.UIFigure);
            app.MasamolEditFieldLabel.Text = 'Masa - mol';
            app.MasamolEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClearallButton = uibutton(app.UIFigure, 'push');
            app.ClearallButton.ButtonPushedFcn = createCallbackFcn(app, @ClearallButtonPushed, true);
            app.ClearallButton.Text = 'Clear all';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.MenButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fmelementos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end