classdef molelementos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MLabel                        matlab.ui.control.Label
        MolaridadLabel                matlab.ui.control.Label
        ResultadoEditField            matlab.ui.control.NumericEditField
        ResultadoEditFieldLabel       matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        Nmerodemolculas6Spinner       matlab.ui.control.Spinner
        Nmerodemolculas6SpinnerLabel  matlab.ui.control.Label
        Nmerodemolculas5Spinner       matlab.ui.control.Spinner
        Nmerodemolculas5SpinnerLabel  matlab.ui.control.Label
        Nmerodemolculas4Spinner       matlab.ui.control.Spinner
        Nmerodemolculas4SpinnerLabel  matlab.ui.control.Label
        Nmerodemolculas3Spinner       matlab.ui.control.Spinner
        Nmerodemolculas3SpinnerLabel  matlab.ui.control.Label
        Nmerodemolculas2Spinner       matlab.ui.control.Spinner
        Nmerodemolculas2SpinnerLabel  matlab.ui.control.Label
        Nmerodemolculas1Spinner       matlab.ui.control.Spinner
        Nmerodemolculas1SpinnerLabel  matlab.ui.control.Label
        Msaatmica6EditField           matlab.ui.control.NumericEditField
        Msaatmica6EditFieldLabel      matlab.ui.control.Label
        Msaatmica4EditField           matlab.ui.control.NumericEditField
        Msaatmica4EditFieldLabel      matlab.ui.control.Label
        Msaatmica5EditField           matlab.ui.control.NumericEditField
        Msaatmica5EditFieldLabel      matlab.ui.control.Label
        Msaatmica3EditField           matlab.ui.control.NumericEditField
        Msaatmica3EditFieldLabel      matlab.ui.control.Label
        Msaatmica2EditField           matlab.ui.control.NumericEditField
        Msaatmica2EditFieldLabel      matlab.ui.control.Label
        Msaatmica1EditField           matlab.ui.control.NumericEditField
        Msaatmica1EditFieldLabel      matlab.ui.control.Label
        MasadelsolutogEditField       matlab.ui.control.NumericEditField
        MasadelsolutogEditFieldLabel  matlab.ui.control.Label
        LitrosKilogramosdedisolucinEditField  matlab.ui.control.NumericEditField
        LitrosKilogramosdedisolucinLabel  matlab.ui.control.Label
        MenButton                     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MenButton
        function MenButtonPushed(app, event)
            menumol
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            ma1=app.Msaatmica1EditField.Value;
            ma2=app.Msaatmica2EditField.Value;
            ma3=app.Msaatmica3EditField.Value;
            ma4=app.Msaatmica4EditField.Value;
            ma5=app.Msaatmica5EditField.Value;
            ma6=app.Msaatmica6EditField.Value;
            nm1=app.Nmerodemolculas1Spinner.Value;
            nm2=app.Nmerodemolculas2Spinner.Value;
            nm3=app.Nmerodemolculas3Spinner.Value;
            nm4=app.Nmerodemolculas4Spinner.Value;
            nm5=app.Nmerodemolculas5Spinner.Value;
            nm6=app.Nmerodemolculas6Spinner.Value;
            mg=app.MasadelsolutogEditField.Value;
            ld=app.LitrosKilogramosdedisolucinEditField.Value;
            Mt=(ma1*nm1)+(ma2*nm2)+(ma3*nm3)+(ma4*nm4)+(ma5*nm5)+(ma6*nm6);
            n=mg/Mt;
            M=n/ld;
            app.ResultadoEditField.Value=M;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MenButton = uibutton(app.UIFigure, 'push');
            app.MenButton.ButtonPushedFcn = createCallbackFcn(app, @MenButtonPushed, true);
            app.MenButton.Icon = '7530664.png';
            app.MenButton.Text = 'Menú';
            app.LitrosKilogramosdedisolucinLabel = uilabel(app.UIFigure);
            app.LitrosKilogramosdedisolucinLabel.Text = 'Litros / Kilogramos de disolución';
            app.LitrosKilogramosdedisolucinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MasadelsolutogEditFieldLabel = uilabel(app.UIFigure);
            app.MasadelsolutogEditFieldLabel.Text = 'Masa del soluto (g)';
            app.MasadelsolutogEditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica1EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica1EditFieldLabel.Text = 'Mása atómica 1';
            app.Msaatmica1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica2EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica2EditFieldLabel.Text = 'Mása atómica 2';
            app.Msaatmica2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica3EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica3EditFieldLabel.Text = 'Mása atómica 3';
            app.Msaatmica3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica5EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica5EditFieldLabel.Text = 'Mása atómica 5';
            app.Msaatmica5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica4EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica4EditFieldLabel.Text = 'Mása atómica 4';
            app.Msaatmica4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Msaatmica6EditFieldLabel = uilabel(app.UIFigure);
            app.Msaatmica6EditFieldLabel.Text = 'Mása atómica 6';
            app.Msaatmica6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Nmerodemolculas1SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas1SpinnerLabel.Text = 'Número de moléculas 1';
            app.Nmerodemolculas1Spinner = uispinner(app.UIFigure);
            app.Nmerodemolculas2SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas2SpinnerLabel.Text = 'Número de moléculas 2';
            app.Nmerodemolculas2Spinner = uispinner(app.UIFigure);
            app.Nmerodemolculas3SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas3SpinnerLabel.Text = 'Número de moléculas 3';
            app.Nmerodemolculas3Spinner = uispinner(app.UIFigure);
            app.Nmerodemolculas4SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas4SpinnerLabel.Text = 'Número de moléculas 4';
            app.Nmerodemolculas4Spinner = uispinner(app.UIFigure);
            app.Nmerodemolculas5SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas5SpinnerLabel.Text = 'Número de moléculas 5';
            app.Nmerodemolculas5Spinner = uispinner(app.UIFigure);
            app.Nmerodemolculas6SpinnerLabel = uilabel(app.UIFigure);
            app.Nmerodemolculas6SpinnerLabel.Text = 'Número de moléculas 6';
            app.Nmerodemolculas6Spinner = uispinner(app.UIFigure);
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = {'Calcular'; ''};
            app.ResultadoEditFieldLabel = uilabel(app.UIFigure);
            app.ResultadoEditFieldLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultadoEditField.Editable = 'off';
            app.MolaridadLabel = uilabel(app.UIFigure);
            app.MolaridadLabel.Text = 'Molaridad';
            app.MLabel = uilabel(app.UIFigure);
            app.MLabel.Text = 'M';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = molelementos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end